classdef app001 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManipulatorWorkVolumeSimulatorUIFigure  matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        SchunkHandTab            matlab.ui.container.Tab
        Finger1Panel_2           matlab.ui.container.Panel
        Joint1Knob_5Label        matlab.ui.control.Label
        Joint1Knob_5             matlab.ui.control.Knob
        Joint2Knob_5Label        matlab.ui.control.Label
        Joint2Knob_5             matlab.ui.control.Knob
        Joint3Knob_5Label        matlab.ui.control.Label
        Joint3Knob_5             matlab.ui.control.Knob
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Finger2Panel_2           matlab.ui.container.Panel
        Joint1Knob_6Label        matlab.ui.control.Label
        Joint1Knob_6             matlab.ui.control.Knob
        Joint2Knob_6Label        matlab.ui.control.Label
        Joint2Knob_6             matlab.ui.control.Knob
        Joint3Knob_6Label        matlab.ui.control.Label
        Joint3Knob_6             matlab.ui.control.Knob
        Label_4                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Finger3Panel_2           matlab.ui.container.Panel
        Label_7                  matlab.ui.control.Label
        Joint2Knob_7Label        matlab.ui.control.Label
        Joint2Knob_7             matlab.ui.control.Knob
        Joint3Knob_7Label        matlab.ui.control.Label
        Joint3Knob_7             matlab.ui.control.Knob
        Label_8                  matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        ToolsPanel               matlab.ui.container.Panel
        ApproximateVolumeButton  matlab.ui.control.Button
        mm3Label                 matlab.ui.control.Label
        AllegroRightHandTab      matlab.ui.container.Tab
        Finger4Panel             matlab.ui.container.Panel
        Joint1Knob_4Label        matlab.ui.control.Label
        Joint1Knob_4             matlab.ui.control.Knob
        Joint2Knob_4Label        matlab.ui.control.Label
        Joint2Knob_4             matlab.ui.control.Knob
        Joint3Knob_4Label        matlab.ui.control.Label
        Joint3Knob_4             matlab.ui.control.Knob
        Joint4Knob_4Label        matlab.ui.control.Label
        Joint4Knob_4             matlab.ui.control.Knob
        Finger3Panel             matlab.ui.container.Panel
        Joint1Knob_3Label        matlab.ui.control.Label
        Joint1Knob_3             matlab.ui.control.Knob
        Joint2Knob_3Label        matlab.ui.control.Label
        Joint2Knob_3             matlab.ui.control.Knob
        Joint3Knob_3Label        matlab.ui.control.Label
        Joint3Knob_3             matlab.ui.control.Knob
        Joint4Knob_3Label        matlab.ui.control.Label
        Joint4Knob_3             matlab.ui.control.Knob
        Finger2Panel             matlab.ui.container.Panel
        Joint1Knob_2Label        matlab.ui.control.Label
        Joint1Knob_2             matlab.ui.control.Knob
        Joint2Knob_2Label        matlab.ui.control.Label
        Joint2Knob_2             matlab.ui.control.Knob
        Joint3Knob_2Label        matlab.ui.control.Label
        Joint3Knob_2             matlab.ui.control.Knob
        Joint4Knob_2Label        matlab.ui.control.Label
        Joint4Knob_2             matlab.ui.control.Knob
        Finger1Panel             matlab.ui.container.Panel
        Joint1KnobLabel          matlab.ui.control.Label
        Joint1Knob               matlab.ui.control.Knob
        Joint2KnobLabel          matlab.ui.control.Label
        Joint2Knob               matlab.ui.control.Knob
        Joint3KnobLabel          matlab.ui.control.Label
        Joint3Knob               matlab.ui.control.Knob
        Joint4KnobLabel          matlab.ui.control.Label
        Joint4Knob               matlab.ui.control.Knob
        plot111Button            matlab.ui.control.Button
        AddaManipulatorTab       matlab.ui.container.Tab
        ControlPanel             matlab.ui.container.Panel
        ZoominButton             matlab.ui.control.Button
        ResetZoomButton          matlab.ui.control.Button
        Rotate3dButton           matlab.ui.control.Button
        PanButton                matlab.ui.control.Button
        SweepSwitchLabel         matlab.ui.control.Label
        SweepSwitch              matlab.ui.control.Switch
        CoordinatesPanel         matlab.ui.container.Panel
        Finger1Label             matlab.ui.control.Label
        Finger2Label             matlab.ui.control.Label
        Finger3Label             matlab.ui.control.Label
    end


    properties (Access = private)
        initizalizeRoboticsToolKit = Initialize();
        %---------------------SCHUNK------------------------%
        finger1 = Finger(3, [16.5 86.5 68.5], 'xxx', 3, 'xzz'); %bottom left
        finger2 = Finger(3, [16.5 86.5 68.5], 'xxx', 3, 'xzz'); %top left
        finger3 = Finger(2, [86.5 68.5], 'xx', 2, 'zz'); %middle right
        PositionsSchunk = [81.5 0 0; 81.5 0 66; 98 57.158 33];
        %--------------------/SCHUNK------------------------%
        
        
    end

    methods (Access = private)
    
        function draw2(app, HandClass)
            if strcmpi(app.SweepSwitch.Value, 'Off')
                cla(app.UIAxes); 
            end

            %graph origin of hand
            hold (app.UIAxes, 'on');
            scatter3(app.UIAxes,HandClass.Origin(1,1), HandClass.Origin(1,2), HandClass.Origin(1,3), '*', 'black');
            %xlabel('x');
           % ylabel('y');
            %zlabel('z');
            
            
            scatter3(app.UIAxes,HandClass.PalmLimit(1,1), HandClass.PalmLimit(1,2), HandClass.PalmLimit(1,3), '*', 'red');
            %hold (app.UIAxes);
            %{
            HandClass.Fingers(1, 1).Coordinates
            HandClass.Fingers(1, 2).Coordinates
            HandClass.Fingers(1, 3).Coordinates
            %}
            for i = 1: size(HandClass.Fingers, 2) %i goes from 1 to the number of columns of fingers (i.e. i iterates through the fingers)
                scatter3(app.UIAxes,HandClass.Fingers(1, i).LineCoordinates(:, 1)+HandClass.Position(i, 1), HandClass.Fingers(1, i).LineCoordinates(:, 2)+HandClass.Position(i, 2), HandClass.Fingers(1, i).LineCoordinates(:, 3)+HandClass.Position(i, 3), 16, 'black', 'filled');
                hold (app.UIAxes, 'on');
                %-------------------- CREATING LINES -----------------------------------------------%
                for j = 1:HandClass.Fingers(1,i).Links %for each link in the i finger, create a line
                    line(app.UIAxes, HandClass.Fingers(1, i).LineCoordinates(j:j+1, 1)+HandClass.Position(i, 1), HandClass.Fingers(1, i).LineCoordinates(j:j+1, 2)+HandClass.Position(i, 2), HandClass.Fingers(1, i).LineCoordinates(j:j+1, 3)+HandClass.Position(i, 3));
                end
                %-----------------------------------------------------------------------------------%
            end
            %scatter3(app.UIAxes,1,1,1)
            grid(app.UIAxes, 'on');
        end
        
        function results = rad(app, deg)
            results = deg*pi/180;
        end


        function startupAngles(app)
            cla(app.UIAxes); 
            app.Joint1Knob_5.Value = 45;
            app.Joint2Knob_5.Value = -45;
            app.Joint3Knob_5.Value = 45;
            app.Joint1Knob_6.Value = -1*app.Joint1Knob_5.Value;
            app.Joint2Knob_6.Value = -45;
            app.Joint3Knob_6.Value = 45;
            app.Joint2Knob_7.Value = 30;
            app.Joint3Knob_7.Value = -30;
            
            app.Label.Text = int2str(app.Joint1Knob_5.Value);
            app.Label_2.Text = int2str(app.Joint2Knob_5.Value);
            app.Label_3.Text = int2str(app.Joint3Knob_5.Value);
            app.Label_4.Text = int2str(app.Joint1Knob_6.Value);
            app.Label_5.Text = int2str(app.Joint2Knob_6.Value);
            app.Label_6.Text = int2str(app.Joint1Knob_6.Value);
            app.Label_7.Text = int2str(app.Joint2Knob_7.Value);
            app.Label_8.Text = int2str(app.Joint3Knob_7.Value); 
            
            Configure(app.finger1, [rad(app, app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_5.Value) rad(app, app.Joint3Knob_5.Value)]); %q1 q2 q3
            Configure(app.finger2, [rad(app, -1*app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_6.Value) rad(app, app.Joint3Knob_6.Value)]); %q4 q5 q6
            Configure(app.finger3, [rad(app, app.Joint2Knob_7.Value) rad(app, app.Joint3Knob_7.Value)]); %q7 %q8

            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupAngles(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %run startup_rvc.m
            %run v_100.m
            startupAngles(app);
        end

        % Button pushed function: Rotate3dButton
        function Rotate3dButtonPushed(app, event)
            rotate3d(app.UIAxes, 'on')
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes, 'on')
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            zoom(app.UIAxes, 'on')
        end

        % Button pushed function: ResetZoomButton
        function ResetZoomButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto'; %fix this
            app.UIAxes.YLimMode = 'auto'; %fix this
        end

        % Button pushed function: plot111Button
        function plot111ButtonPushed(app, event)
            scatter3(app.UIAxes,1,1,1)
            grid(app.UIAxes, 'on')
        end

        % Value changing function: Joint1Knob_5
        function Joint1Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint1Knob_6.Value = -changingValue;
            app.Label.Text = int2str(changingValue);
            app.Label_4.Text = int2str(-changingValue);
            Configure(app.finger1, [rad(app, changingValue) rad(app, app.Joint2Knob_5.Value) rad(app, app.Joint3Knob_5.Value)]);
            Configure(app.finger2, [rad(app, -changingValue) rad(app, app.Joint2Knob_6.Value) rad(app, app.Joint3Knob_6.Value)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint2Knob_5
        function Joint2Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_2.Text = int2str(changingValue);
            Configure(app.finger1, [rad(app, app.Joint1Knob_5.Value) rad(app, changingValue) rad(app, app.Joint3Knob_5.Value)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint3Knob_5
        function Joint3Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = int2str(changingValue);
            Configure(app.finger1, [rad(app, app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_5.Value) rad(app, changingValue)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint2Knob_6
        function Joint2Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_5.Text = int2str(changingValue);
            Configure(app.finger2, [rad(app, app.Joint1Knob_6.Value) rad(app, changingValue) rad(app, app.Joint3Knob_6.Value)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint3Knob_6
        function Joint3Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_6.Text = int2str(changingValue);
            Configure(app.finger2, [rad(app, app.Joint1Knob_6.Value) rad(app, app.Joint2Knob_6.Value) rad(app, changingValue)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint2Knob_7
        function Joint2Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_7.Text = int2str(changingValue);
            Configure(app.finger3, [rad(app, changingValue) rad(app, app.Joint3Knob_7.Value)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Value changing function: Joint3Knob_7
        function Joint3Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_8.Text = int2str(changingValue);
            Configure(app.finger3, [rad(app, app.Joint2Knob_7.Value) rad(app, changingValue)]);
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            draw2(app, Schunk);
        end

        % Button pushed function: ApproximateVolumeButton
        function ApproximateVolumeButtonPushed(app, event)
            Configure(app.finger1, [rad(app, app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_5.Value) rad(app, app.Joint3Knob_5.Value)]); %q1 q2 q3
            Configure(app.finger2, [rad(app, -1*app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_6.Value) rad(app, app.Joint3Knob_6.Value)]); %q4 q5 q6
            Configure(app.finger3, [rad(app, app.Joint2Knob_7.Value) rad(app, app.Joint3Knob_7.Value)]); %q7 %q8
            Schunk = Hand([app.finger1 app.finger2 app.finger3], app.PositionsSchunk, [0 0 0], [0 0 0]);
            
            polyhedronCoordinates = [0 0 0];
            
            for i = 1: size(Schunk.Fingers, 2) %i goes from 1 to the number of columns of fingers (i.e. i iterates through the fingers)
                polyhedronCoordinates = [
                    polyhedronCoordinates
                    Schunk.Fingers(1, i).LineCoordinates(:, 1)+Schunk.Position(i, 1), Schunk.Fingers(1, i).LineCoordinates(:, 2)+Schunk.Position(i, 2), Schunk.Fingers(1, i).LineCoordinates(:, 3)+Schunk.Position(i, 3)
                ];
            end
            shp = alphaShape(polyhedronCoordinates);
            plot(shp, 'Parent', app.UIAxes);
            app.mm3Label.Text = int2str(volume(shp)) + "mm^3";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManipulatorWorkVolumeSimulatorUIFigure = uifigure;
            app.ManipulatorWorkVolumeSimulatorUIFigure.Name = 'Manipulator Work Volume Simulator';
            app.UIAxes = uiaxes(app.ManipulatorWorkVolumeSimulatorUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.ManipulatorWorkVolumeSimulatorUIFigure);
            app.SchunkHandTab = uitab(app.TabGroup);
            app.SchunkHandTab.Title = 'Schunk Hand';
            app.Finger1Panel_2 = uipanel(app.SchunkHandTab);
            app.Finger1Panel_2.Title = 'Finger 1';
            app.Joint1Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint1Knob_5Label.Text = 'Joint 1';
            app.Joint1Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint1Knob_5.Limits = [0 90];
            app.Joint1Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint1Knob_5ValueChanging, true);
            app.Joint1Knob_5.Value = 45;
            app.Joint2Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint2Knob_5Label.Text = 'Joint 2';
            app.Joint2Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint2Knob_5.Limits = [-90 90];
            app.Joint2Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_5ValueChanging, true);
            app.Joint2Knob_5.Value = -45;
            app.Joint3Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint3Knob_5Label.Text = 'Joint 3';
            app.Joint3Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint3Knob_5.Limits = [-90 90];
            app.Joint3Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint3Knob_5ValueChanging, true);
            app.Joint3Knob_5.Value = 45;
            app.Label = uilabel(app.Finger1Panel_2);
            app.Label.Text = '45';
            app.Label_2 = uilabel(app.Finger1Panel_2);
            app.Label_2.Text = '-45';
            app.Label_3 = uilabel(app.Finger1Panel_2);
            app.Label_3.Text = '45';
            app.Finger2Panel_2 = uipanel(app.SchunkHandTab);
            app.Finger2Panel_2.Title = 'Finger 2';
            app.Joint1Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint1Knob_6Label.Enable = 'off';
            app.Joint1Knob_6Label.Text = 'Joint 1';
            app.Joint1Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint1Knob_6.Limits = [-90 0];
            app.Joint1Knob_6.Enable = 'off';
            app.Joint1Knob_6.Value = -45;
            app.Joint2Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint2Knob_6Label.Text = 'Joint 2';
            app.Joint2Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint2Knob_6.Limits = [-90 90];
            app.Joint2Knob_6.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_6ValueChanging, true);
            app.Joint2Knob_6.Value = -45;
            app.Joint3Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint3Knob_6Label.Text = 'Joint 3';
            app.Joint3Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint3Knob_6.Limits = [-90 90];
            app.Joint3Knob_6.ValueChangingFcn = createCallbackFcn(app, @Joint3Knob_6ValueChanging, true);
            app.Joint3Knob_6.Value = 45;
            app.Label_4 = uilabel(app.Finger2Panel_2);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '-45';
            app.Label_5 = uilabel(app.Finger2Panel_2);
            app.Label_5.Text = '-45';
            app.Label_6 = uilabel(app.Finger2Panel_2);
            app.Label_6.Text = '45';
            app.Finger3Panel_2 = uipanel(app.SchunkHandTab);
            app.Finger3Panel_2.Title = 'Finger 3';
            app.Label_7 = uilabel(app.Finger3Panel_2);
            app.Label_7.Text = '30';
            app.Joint2Knob_7Label = uilabel(app.Finger3Panel_2);
            app.Joint2Knob_7Label.Text = 'Joint 2';
            app.Joint2Knob_7 = uiknob(app.Finger3Panel_2, 'continuous');
            app.Joint2Knob_7.Limits = [-90 90];
            app.Joint2Knob_7.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_7ValueChanging, true);
            app.Joint2Knob_7.Value = 30;
            app.Joint3Knob_7Label = uilabel(app.Finger3Panel_2);
            app.Joint3Knob_7Label.Text = 'Joint 3';
            app.Joint3Knob_7 = uiknob(app.Finger3Panel_2, 'continuous');
            app.Joint3Knob_7.Limits = [-90 90];
            app.Joint3Knob_7.ValueChangingFcn = createCallbackFcn(app, @Joint3Knob_7ValueChanging, true);
            app.Joint3Knob_7.Value = -30;
            app.Label_8 = uilabel(app.Finger3Panel_2);
            app.Label_8.Text = '-30';
            app.ResetButton = uibutton(app.SchunkHandTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ToolsPanel = uipanel(app.SchunkHandTab);
            app.ToolsPanel.Title = 'Tools';
            app.ApproximateVolumeButton = uibutton(app.ToolsPanel, 'push');
            app.ApproximateVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateVolumeButtonPushed, true);
            app.ApproximateVolumeButton.Text = 'Approximate Volume';
            app.mm3Label = uilabel(app.ToolsPanel);
            app.mm3Label.Text = '0 mm^3';
            app.AllegroRightHandTab = uitab(app.TabGroup);
            app.AllegroRightHandTab.Title = 'Allegro Right Hand';
            app.Finger4Panel = uipanel(app.AllegroRightHandTab);
            app.Finger4Panel.Title = 'Finger 4';
            app.Joint1Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint1Knob_4Label.Text = 'Joint 1';
            app.Joint1Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint2Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint2Knob_4Label.Text = 'Joint 2';
            app.Joint2Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint3Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint3Knob_4Label.Text = 'Joint 3';
            app.Joint3Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint4Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint4Knob_4Label.Text = 'Joint 4';
            app.Joint4Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Finger3Panel = uipanel(app.AllegroRightHandTab);
            app.Finger3Panel.Title = 'Finger 3';
            app.Joint1Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint1Knob_3Label.Text = 'Joint 1';
            app.Joint1Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint2Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint2Knob_3Label.Text = 'Joint 2';
            app.Joint2Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint3Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint3Knob_3Label.Text = 'Joint 3';
            app.Joint3Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint4Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint4Knob_3Label.Text = 'Joint 4';
            app.Joint4Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Finger2Panel = uipanel(app.AllegroRightHandTab);
            app.Finger2Panel.Title = 'Finger 2';
            app.Joint1Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint1Knob_2Label.Text = 'Joint 1';
            app.Joint1Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint2Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint2Knob_2Label.Text = 'Joint 2';
            app.Joint2Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint3Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint3Knob_2Label.Text = 'Joint 3';
            app.Joint3Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint4Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint4Knob_2Label.Text = 'Joint 4';
            app.Joint4Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Finger1Panel = uipanel(app.AllegroRightHandTab);
            app.Finger1Panel.Title = 'Finger 1';
            app.Joint1KnobLabel = uilabel(app.Finger1Panel);
            app.Joint1KnobLabel.Text = 'Joint 1';
            app.Joint1Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint2KnobLabel = uilabel(app.Finger1Panel);
            app.Joint2KnobLabel.Text = 'Joint 2';
            app.Joint2Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint3KnobLabel = uilabel(app.Finger1Panel);
            app.Joint3KnobLabel.Text = 'Joint 3';
            app.Joint3Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint4KnobLabel = uilabel(app.Finger1Panel);
            app.Joint4KnobLabel.Text = 'Joint 4';
            app.Joint4Knob = uiknob(app.Finger1Panel, 'continuous');
            app.plot111Button = uibutton(app.AllegroRightHandTab, 'push');
            app.plot111Button.ButtonPushedFcn = createCallbackFcn(app, @plot111ButtonPushed, true);
            app.plot111Button.Text = 'plot(1,1,1)';
            app.AddaManipulatorTab = uitab(app.TabGroup);
            app.AddaManipulatorTab.Title = 'Add a Manipulator...';
            app.ControlPanel = uipanel(app.ManipulatorWorkVolumeSimulatorUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.ZoominButton = uibutton(app.ControlPanel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ResetZoomButton = uibutton(app.ControlPanel, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.Rotate3dButton = uibutton(app.ControlPanel, 'push');
            app.Rotate3dButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate3dButtonPushed, true);
            app.Rotate3dButton.Text = 'Rotate 3d';
            app.PanButton = uibutton(app.ControlPanel, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.SweepSwitchLabel = uilabel(app.ControlPanel);
            app.SweepSwitchLabel.Text = 'Sweep';
            app.SweepSwitch = uiswitch(app.ControlPanel, 'slider');
            app.CoordinatesPanel = uipanel(app.ManipulatorWorkVolumeSimulatorUIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.Finger1Label = uilabel(app.CoordinatesPanel);
            app.Finger1Label.Text = 'Finger 1';
            app.Finger2Label = uilabel(app.CoordinatesPanel);
            app.Finger2Label.Text = 'Finger 2';
            app.Finger3Label = uilabel(app.CoordinatesPanel);
            app.Finger3Label.Text = 'Finger 3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app001

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManipulatorWorkVolumeSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManipulatorWorkVolumeSimulatorUIFigure)
        end
    end
end