classdef app002 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManipulatorWorkVolumeSimulatorUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        SchunkDexterousHandTab      matlab.ui.container.Tab
        Finger1Panel_2              matlab.ui.container.Panel
        Joint1Knob_5Label           matlab.ui.control.Label
        Joint1Knob_5                matlab.ui.control.Knob
        Joint2Knob_5Label           matlab.ui.control.Label
        Joint2Knob_5                matlab.ui.control.Knob
        Joint3Knob_5Label           matlab.ui.control.Label
        Joint3Knob_5                matlab.ui.control.Knob
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Finger2Panel_2              matlab.ui.container.Panel
        Joint1Knob_6Label           matlab.ui.control.Label
        Joint1Knob_6                matlab.ui.control.Knob
        Joint2Knob_6Label           matlab.ui.control.Label
        Joint2Knob_6                matlab.ui.control.Knob
        Joint3Knob_6Label           matlab.ui.control.Label
        Joint3Knob_6                matlab.ui.control.Knob
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Finger3Panel_2              matlab.ui.container.Panel
        Label_7                     matlab.ui.control.Label
        Joint1Knob_7Label           matlab.ui.control.Label
        Joint1Knob_7                matlab.ui.control.Knob
        Joint2Knob_7Label           matlab.ui.control.Label
        Joint2Knob_7                matlab.ui.control.Knob
        Label_8                     matlab.ui.control.Label
        ToolsPanel                  matlab.ui.container.Panel
        ApproximateVolumeButton     matlab.ui.control.Button
        mm3Label                    matlab.ui.control.Label
        SweepSwitchLabel            matlab.ui.control.Label
        SweepSwitch                 matlab.ui.control.Switch
        MaximizeVolumewithinGraspTypeButton  matlab.ui.control.Button
        mm3Label_2                  matlab.ui.control.Label
        ImportPrimitiveShapeButton  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ExportasSTLFileButton       matlab.ui.control.Button
        CoordinatesPanel            matlab.ui.container.Panel
        Joint2Label                 matlab.ui.control.Label
        Joint3Label                 matlab.ui.control.Label
        FingertipLabel              matlab.ui.control.Label
        CoordinatesPanel_2          matlab.ui.container.Panel
        Joint2Label_2               matlab.ui.control.Label
        Joint3Label_2               matlab.ui.control.Label
        FingertipLabel_2            matlab.ui.control.Label
        CoordinatesPanel_3          matlab.ui.container.Panel
        Joint1Label                 matlab.ui.control.Label
        Joint2Label_3               matlab.ui.control.Label
        FingertipLabel_3            matlab.ui.control.Label
        AllegroRightHandTab         matlab.ui.container.Tab
        Finger4Panel                matlab.ui.container.Panel
        Joint1Knob_4Label           matlab.ui.control.Label
        Joint1Knob_4                matlab.ui.control.Knob
        Joint2Knob_4Label           matlab.ui.control.Label
        Joint2Knob_4                matlab.ui.control.Knob
        Joint3Knob_4Label           matlab.ui.control.Label
        Joint3Knob_4                matlab.ui.control.Knob
        Joint4Knob_4Label           matlab.ui.control.Label
        Joint4Knob_4                matlab.ui.control.Knob
        Finger3Panel                matlab.ui.container.Panel
        Joint1Knob_3Label           matlab.ui.control.Label
        Joint1Knob_3                matlab.ui.control.Knob
        Joint2Knob_3Label           matlab.ui.control.Label
        Joint2Knob_3                matlab.ui.control.Knob
        Joint3Knob_3Label           matlab.ui.control.Label
        Joint3Knob_3                matlab.ui.control.Knob
        Joint4Knob_3Label           matlab.ui.control.Label
        Joint4Knob_3                matlab.ui.control.Knob
        Finger2Panel                matlab.ui.container.Panel
        Joint1Knob_2Label           matlab.ui.control.Label
        Joint1Knob_2                matlab.ui.control.Knob
        Joint2Knob_2Label           matlab.ui.control.Label
        Joint2Knob_2                matlab.ui.control.Knob
        Joint3Knob_2Label           matlab.ui.control.Label
        Joint3Knob_2                matlab.ui.control.Knob
        Joint4Knob_2Label           matlab.ui.control.Label
        Joint4Knob_2                matlab.ui.control.Knob
        Finger1Panel                matlab.ui.container.Panel
        Joint1KnobLabel             matlab.ui.control.Label
        Joint1Knob                  matlab.ui.control.Knob
        Joint2KnobLabel             matlab.ui.control.Label
        Joint2Knob                  matlab.ui.control.Knob
        Joint3KnobLabel             matlab.ui.control.Label
        Joint3Knob                  matlab.ui.control.Knob
        Joint4KnobLabel             matlab.ui.control.Label
        Joint4Knob                  matlab.ui.control.Knob
        AddaManipulatorTab          matlab.ui.container.Tab
        ControlPanel                matlab.ui.container.Panel
        ZoominButton                matlab.ui.control.Button
        ResetZoomButton             matlab.ui.control.Button
        Rotate3dButton              matlab.ui.control.Button
        PanButton                   matlab.ui.control.Button
        AxisLimitsSwitchLabel       matlab.ui.control.Label
        AxisLimitsSwitch            matlab.ui.control.Switch
    end


    properties (Access = private)
        initizalizeRoboticsToolKit = Initialize();
        %---------------------SCHUNK------------------------%
        Schunk = SDH();
        %--------------------/SCHUNK------------------------%
        
    end

    methods (Access = private)
    
        function draw2(app, HandClass)
            if strcmpi(app.SweepSwitch.Value, 'Off')
                cla(app.UIAxes); 
            end

            %graph origin of hand
            hold (app.UIAxes, 'on');
            scatter3(app.UIAxes,HandClass.Origin(1,1), HandClass.Origin(1,2), HandClass.Origin(1,3), '*', 'black');
            
            %graph palm limit of hand
            scatter3(app.UIAxes,HandClass.PalmLimit(1,1), HandClass.PalmLimit(1,2), HandClass.PalmLimit(1,3), '*', 'red');

            %graph coordinates of joints and fingertips
            graphCoordinates = HandClass.getVolCoordinates();
            scatter3(app.UIAxes, graphCoordinates(:,1), graphCoordinates(:,2), graphCoordinates(:,3), 16, 'black', 'filled');
            hold (app.UIAxes, 'on');
            
            %-------------------- CREATING LINES -----------------------------------------------%
            for i = 1: size(HandClass.Fingers, 2)
                for j = 1:HandClass.Fingers(1,i).Links %for each link in the i finger, create a line
                    line(app.UIAxes, HandClass.Fingers(1, i).LineCoordinates(j:j+1, 1)+HandClass.Position(i, 1), HandClass.Fingers(1, i).LineCoordinates(j:j+1, 2)+HandClass.Position(i, 2), HandClass.Fingers(1, i).LineCoordinates(j:j+1, 3)+HandClass.Position(i, 3));
                end
            end
            %-----------------------------------------------------------------------------------%
          
            grid(app.UIAxes, 'on');
        end
        
        function results = rad(app, deg)
            results = deg*pi/180;
        end


        function startupAngles(app)
            cla(app.UIAxes); 
            app.Joint1Knob_5.Value = 45;
            app.Joint2Knob_5.Value = -45;
            app.Joint3Knob_5.Value = 45;
            app.Joint1Knob_6.Value = -1*app.Joint1Knob_5.Value;
            app.Joint2Knob_6.Value = -45;
            app.Joint3Knob_6.Value = 45;
            app.Joint1Knob_7.Value = 30;
            app.Joint2Knob_7.Value = -30;
            
            app.Label.Text = int2str(app.Joint1Knob_5.Value);
            app.Label_2.Text = int2str(app.Joint2Knob_5.Value);
            app.Label_3.Text = int2str(app.Joint3Knob_5.Value);
            app.Label_4.Text = int2str(app.Joint1Knob_6.Value);
            app.Label_5.Text = int2str(app.Joint2Knob_6.Value);
            app.Label_6.Text = int2str(app.Joint1Knob_6.Value);
            app.Label_7.Text = int2str(app.Joint1Knob_7.Value);
            app.Label_8.Text = int2str(app.Joint2Knob_7.Value); 

            app.Schunk.configureDefaultFingers();
            
            %FingerCoords = app.Schunk.getVolCoordinates()
            
            Finger1 = app.Schunk.getFinger1VolCoordinates();
            Finger2 = app.Schunk.getFinger2VolCoordinates();
            Finger3 = app.Schunk.getFinger3VolCoordinates();
            
            app.Joint2Label.Text = "Joint 2:   (" + int2str(Finger1(1,1)) + ", " + int2str(Finger1(1,2)) + ", " + int2str(Finger1(1,3)) + ")";
            app.Joint3Label.Text = "Joint 3:   (" + int2str(Finger1(2,1)) + ", " + int2str(Finger1(2,2)) + ", " + int2str(Finger1(2,3)) + ")";
            app.FingertipLabel.Text = "Fingertip: (" + int2str(Finger1(3,1)) + ", " + int2str(Finger1(3,2)) + ", " + int2str(Finger1(3,3)) + ")";
            app.Joint2Label_2.Text = "Joint 2:   (" + int2str(Finger2(1,1)) + ", " + int2str(Finger2(1,2)) + ", " + int2str(Finger2(1,3)) + ")";
            app.Joint3Label_2.Text = "Joint 3:   (" + int2str(Finger2(2,1)) + ", " + int2str(Finger2(2,2)) + ", " + int2str(Finger2(2,3)) + ")";
            app.FingertipLabel_2.Text = "Fingertip: (" + int2str(Finger2(3,1)) + ", " + int2str(Finger2(3,2)) + ", " + int2str(Finger2(3,3)) + ")";
            app.Joint1Label.Text = "Joint 1:   (" + int2str(Finger3(1,1)) + ", " + int2str(Finger3(1,2)) + ", " + int2str(Finger3(1,3)) + ")";
            app.Joint2Label_3.Text = "Joint 2:   (" + int2str(Finger3(2,1)) + ", " + int2str(Finger3(2,2)) + ", " + int2str(Finger3(2,3)) + ")";
            app.FingertipLabel_3.Text = "Fingertip: (" + int2str(Finger3(3,1)) + ", " + int2str(Finger3(3,2)) + ", " + int2str(Finger3(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupAngles(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %run startup_rvc.m
            %run v_100.m
            startupAngles(app);
        end

        % Button pushed function: Rotate3dButton
        function Rotate3dButtonPushed(app, event)
            rotate3d(app.UIAxes, 'on')
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes, 'on')
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            zoom(app.UIAxes, 'on')
        end

        % Button pushed function: ResetZoomButton
        function ResetZoomButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto'; %fix this
            app.UIAxes.YLimMode = 'auto'; %fix this
        end

        % Callback function
        function plot111ButtonPushed(app, event)
            scatter3(app.UIAxes,1,1,1)
            grid(app.UIAxes, 'on')
        end

        % Value changing function: Joint1Knob_5
        function Joint1Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Joint1Knob_6.Value = -changingValue;
            app.Label.Text = int2str(changingValue);
            app.Label_4.Text = int2str(-changingValue);
            
            app.Schunk.configureFinger1([rad(app, changingValue) rad(app, app.Joint2Knob_5.Value) rad(app, app.Joint3Knob_5.Value)]);
            app.Schunk.configureFinger2([rad(app, -changingValue) rad(app, app.Joint2Knob_6.Value) rad(app, app.Joint3Knob_6.Value)]);
            
            Finger1 = app.Schunk.getFinger1VolCoordinates();
            Finger2 = app.Schunk.getFinger2VolCoordinates();
            
            %app.Joint2Label.Text = "Joint 2:   (" + int2str(Finger1(1,1)) + ", " + int2str(Finger1(1,2)) + ", " + int2str(Finger1(1,3)) + ")";
            app.Joint3Label.Text = "Joint 3:   (" + int2str(Finger1(2,1)) + ", " + int2str(Finger1(2,2)) + ", " + int2str(Finger1(2,3)) + ")";
            app.FingertipLabel.Text = "Fingertip: (" + int2str(Finger1(3,1)) + ", " + int2str(Finger1(3,2)) + ", " + int2str(Finger1(3,3)) + ")";
            %app.Joint2Label_2.Text = "Joint 2:   (" + int2str(Finger2(1,1)) + ", " + int2str(Finger2(1,2)) + ", " + int2str(Finger2(1,3)) + ")";
            app.Joint3Label_2.Text = "Joint 3:   (" + int2str(Finger2(2,1)) + ", " + int2str(Finger2(2,2)) + ", " + int2str(Finger2(2,3)) + ")";
            app.FingertipLabel_2.Text = "Fingertip: (" + int2str(Finger2(3,1)) + ", " + int2str(Finger2(3,2)) + ", " + int2str(Finger2(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint2Knob_5
        function Joint2Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_2.Text = int2str(changingValue);
            app.Schunk.configureFinger1([rad(app, app.Joint1Knob_5.Value) rad(app, changingValue) rad(app, app.Joint3Knob_5.Value)]);
            
            Finger1 = app.Schunk.getFinger1VolCoordinates();
            app.Joint3Label.Text = "Joint 3:   (" + int2str(Finger1(2,1)) + ", " + int2str(Finger1(2,2)) + ", " + int2str(Finger1(2,3)) + ")";
            app.FingertipLabel.Text = "Fingertip: (" + int2str(Finger1(3,1)) + ", " + int2str(Finger1(3,2)) + ", " + int2str(Finger1(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint3Knob_5
        function Joint3Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = int2str(changingValue);
            app.Schunk.configureFinger1([rad(app, app.Joint1Knob_5.Value) rad(app, app.Joint2Knob_5.Value) rad(app, changingValue)]);
            
            Finger1 = app.Schunk.getFinger1VolCoordinates();
            app.FingertipLabel.Text = "Fingertip: (" + int2str(Finger1(3,1)) + ", " + int2str(Finger1(3,2)) + ", " + int2str(Finger1(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint2Knob_6
        function Joint2Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_5.Text = int2str(changingValue);
            app.Schunk.configureFinger2([rad(app, app.Joint1Knob_6.Value) rad(app, changingValue) rad(app, app.Joint3Knob_6.Value)]);
            
            Finger2 = app.Schunk.getFinger2VolCoordinates();
            app.Joint3Label_2.Text = "Joint 3:   (" + int2str(Finger2(2,1)) + ", " + int2str(Finger2(2,2)) + ", " + int2str(Finger2(2,3)) + ")";
            app.FingertipLabel_2.Text = "Fingertip: (" + int2str(Finger2(3,1)) + ", " + int2str(Finger2(3,2)) + ", " + int2str(Finger2(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint3Knob_6
        function Joint3Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_6.Text = int2str(changingValue);
            app.Schunk.configureFinger2([rad(app, app.Joint1Knob_6.Value) rad(app, app.Joint2Knob_6.Value) rad(app, changingValue)]);
            
            Finger2 = app.Schunk.getFinger2VolCoordinates();
            app.FingertipLabel_2.Text = "Fingertip: (" + int2str(Finger2(3,1)) + ", " + int2str(Finger2(3,2)) + ", " + int2str(Finger2(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint1Knob_7
        function Joint1Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_7.Text = int2str(changingValue);
            app.Schunk.configureFinger3([rad(app, changingValue) rad(app, app.Joint2Knob_7.Value)]);
            
            Finger3 = app.Schunk.getFinger3VolCoordinates();
            app.Joint2Label_3.Text = "Joint 2:   (" + int2str(Finger3(2,1)) + ", " + int2str(Finger3(2,2)) + ", " + int2str(Finger3(2,3)) + ")";
            app.FingertipLabel_3.Text = "Fingertip: (" + int2str(Finger3(3,1)) + ", " + int2str(Finger3(3,2)) + ", " + int2str(Finger3(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Value changing function: Joint2Knob_7
        function Joint2Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_8.Text = int2str(changingValue);
            app.Schunk.configureFinger3([rad(app, app.Joint1Knob_7.Value) rad(app, changingValue)]);
            
            Finger3 = app.Schunk.getFinger3VolCoordinates();
            app.FingertipLabel_3.Text = "Fingertip: (" + int2str(Finger3(3,1)) + ", " + int2str(Finger3(3,2)) + ", " + int2str(Finger3(3,3)) + ")";
            
            draw2(app, app.Schunk);
        end

        % Button pushed function: ApproximateVolumeButton
        function ApproximateVolumeButtonPushed(app, event)
            shp = alphaShape(app.Schunk.getVolCoordinates(), 100000);
            plot(shp, 'Parent', app.UIAxes);
            app.mm3Label.Text = int2str(volume(shp)) + " mm^3";
            app.ExportasSTLFileButton.Visible = true;
        end

        % Value changed function: AxisLimitsSwitch
        function AxisLimitsSwitchValueChanged(app, event)
            value = app.AxisLimitsSwitch.Value;
            if strcmpi(value, 'Manual')
                app.UIAxes.XLim = [90, 260];
                app.UIAxes.YLim = [-110, 200];
                app.UIAxes.ZLim = [-150, 210];
                app.UIAxes.XTick = 90:20:260;
                app.UIAxes.YTick = -110:20:200;
                app.UIAxes.ZTick = -150:20:210;
            else
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.ZLimMode = 'auto';
            end
        end

        % Button pushed function: ExportasSTLFileButton
        function ExportasSTLFileButtonPushed(app, event)
            shp = alphaShape(app.Schunk.getVolCoordinates(), 100000);
            
            [bf, P] = boundaryFacets(shp);
            stlwrite(triangulation(bf, P), 'STLfileFromApp.stl');
            
            
            %[T, P] = alphaTriangulation(shp);
            %TR = triangulation(T, P);
            
            %stlwrite(TR, 'STLfileFromApp.stl');
        end

        % Button pushed function: 
        % MaximizeVolumewithinGraspTypeButton
        function MaximizeVolumewithinGraspTypeButtonPushed(app, event)
            maxVolume = 0;    
        
            jointAngle1 = app.Joint1Knob_5.Value;
            jointAngle2 = app.Joint2Knob_5.Value;
            jointAngle3 = app.Joint3Knob_5.Value;
            jointAngle5 = app.Joint2Knob_6.Value;
            jointAngle6 = app.Joint3Knob_6.Value;
            jointAngle7 = app.Joint1Knob_7.Value;
            jointAngle8 = app.Joint2Knob_7.Value;
            
            for i = (jointAngle1)-5:(jointAngle1)+5
                app.Joint1Knob_5.Value = i;
                app.Label.Text = int2str(i);
                
                app.Joint1Knob_6.Value = -i;
                app.Label_4.Text = int2str(-i);
                
                for j = (jointAngle2)-5:(jointAngle2)+5
                    app.Joint2Knob_5.Value = j;
                    app.Label_2.Text = int2str(j);
                    
                    for k = (jointAngle3)-5:(jointAngle3)+5
                        app.Joint3Knob_5.Value = k;
                        app.Label_3.Text = int2str(k);
                        
                        app.Schunk.configureFinger1([app.rad(i) app.rad(j) app.rad(k)]);
                        Finger1 = app.Schunk.getFinger1VolCoordinates();
                        
                        app.Joint3Label.Text = "Joint 3:   (" + int2str(Finger1(2,1)) + ", " + int2str(Finger1(2,2)) + ", " + int2str(Finger1(2,3)) + ")";
                        app.FingertipLabel.Text = "Fingertip: (" + int2str(Finger1(3,1)) + ", " + int2str(Finger1(3,2)) + ", " + int2str(Finger1(3,3)) + ")";
                            
                        for l = (jointAngle5)-5:(jointAngle5)+5 %start of second finger
                            app.Joint2Knob_6.Value = l;
                            app.Label_5.Text = int2str(l);
                            
                            for m = (jointAngle6)-5:(jointAngle6)+5
                                app.Joint3Knob_6.Value = m;
                                app.Label_6.Text = int2str(m);
                                
                                app.Schunk.configureFinger2([app.rad(-i) app.rad(l) app.rad(m)]);
                                Finger2 = app.Schunk.getFinger2VolCoordinates();
                                
                                app.Joint3Label_2.Text = "Joint 3:   (" + int2str(Finger2(2,1)) + ", " + int2str(Finger2(2,2)) + ", " + int2str(Finger2(2,3)) + ")";
                                app.FingertipLabel_2.Text = "Fingertip: (" + int2str(Finger2(3,1)) + ", " + int2str(Finger2(3,2)) + ", " + int2str(Finger2(3,3)) + ")";
                                
                                for n = (jointAngle7)-5:(jointAngle7)+5 %start of third finger
                                    app.Joint1Knob_7.Value = n;
                                    app.Label_7.Text = int2str(n);
                                    
                                    for o = (jointAngle8)-5:(jointAngle8)+5
                                        app.Joint2Knob_7.Value = o;
                                        app.Label_8.Text = int2str(o);
                                        
                                        app.Schunk.configureFinger3([app.rad(n) app.rad(o)]);
                                        Finger3 = app.Schunk.getFinger3VolCoordinates();
                                        
                                        app.Joint2Label_3.Text = "Joint 2:   (" + int2str(Finger3(2,1)) + ", " + int2str(Finger3(2,2)) + ", " + int2str(Finger3(2,3)) + ")";
                                        app.FingertipLabel_3.Text = "Fingertip: (" + int2str(Finger3(3,1)) + ", " + int2str(Finger3(3,2)) + ", " + int2str(Finger3(3,3)) + ")";
                                        
                                        cla(app.UIAxes);
                                        app.draw2(app.Schunk);
                                        
                                        shp = alphaShape(app.Schunk.getVolCoordinates(), 100000);
                                        plot(shp, 'Parent', app.UIAxes);
                                        disp('Getting Called!');
                                        if(volume(shp) > maxVolume)
                                            maxVolume = volume(shp);
                                            app.mm3Label_2.Text = int2str(maxVolume) + " mm^3";
                                        end
                                        pause(0.2);
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManipulatorWorkVolumeSimulatorUIFigure = uifigure('Visible', 'off');
            app.ManipulatorWorkVolumeSimulatorUIFigure.Name = 'Manipulator Work Volume Simulator';
            app.UIAxes = uiaxes(app.ManipulatorWorkVolumeSimulatorUIFigure);
            title(app.UIAxes, 'Graph Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.ManipulatorWorkVolumeSimulatorUIFigure);
            app.SchunkDexterousHandTab = uitab(app.TabGroup);
            app.SchunkDexterousHandTab.Title = 'Schunk Dexterous Hand';
            app.Finger1Panel_2 = uipanel(app.SchunkDexterousHandTab);
            app.Finger1Panel_2.Title = 'Finger 1';
            app.Joint1Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint1Knob_5Label.Text = 'Joint 1';
            app.Joint1Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint1Knob_5.Limits = [0 90];
            app.Joint1Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint1Knob_5ValueChanging, true);
            app.Joint1Knob_5.Value = 45;
            app.Joint2Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint2Knob_5Label.Text = 'Joint 2';
            app.Joint2Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint2Knob_5.Limits = [-90 90];
            app.Joint2Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_5ValueChanging, true);
            app.Joint2Knob_5.Value = -45;
            app.Joint3Knob_5Label = uilabel(app.Finger1Panel_2);
            app.Joint3Knob_5Label.Text = 'Joint 3';
            app.Joint3Knob_5 = uiknob(app.Finger1Panel_2, 'continuous');
            app.Joint3Knob_5.Limits = [-90 90];
            app.Joint3Knob_5.ValueChangingFcn = createCallbackFcn(app, @Joint3Knob_5ValueChanging, true);
            app.Joint3Knob_5.Value = 45;
            app.Label = uilabel(app.Finger1Panel_2);
            app.Label.Text = '45';
            app.Label_2 = uilabel(app.Finger1Panel_2);
            app.Label_2.Text = '-45';
            app.Label_3 = uilabel(app.Finger1Panel_2);
            app.Label_3.Text = '45';
            app.Finger2Panel_2 = uipanel(app.SchunkDexterousHandTab);
            app.Finger2Panel_2.Title = 'Finger 2';
            app.Joint1Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint1Knob_6Label.Enable = 'off';
            app.Joint1Knob_6Label.Text = 'Joint 1';
            app.Joint1Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint1Knob_6.Limits = [-90 0];
            app.Joint1Knob_6.Enable = 'off';
            app.Joint1Knob_6.Value = -45;
            app.Joint2Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint2Knob_6Label.Text = 'Joint 2';
            app.Joint2Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint2Knob_6.Limits = [-90 90];
            app.Joint2Knob_6.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_6ValueChanging, true);
            app.Joint2Knob_6.Value = -45;
            app.Joint3Knob_6Label = uilabel(app.Finger2Panel_2);
            app.Joint3Knob_6Label.Text = 'Joint 3';
            app.Joint3Knob_6 = uiknob(app.Finger2Panel_2, 'continuous');
            app.Joint3Knob_6.Limits = [-90 90];
            app.Joint3Knob_6.ValueChangingFcn = createCallbackFcn(app, @Joint3Knob_6ValueChanging, true);
            app.Joint3Knob_6.Value = 45;
            app.Label_4 = uilabel(app.Finger2Panel_2);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '-45';
            app.Label_5 = uilabel(app.Finger2Panel_2);
            app.Label_5.Text = '-45';
            app.Label_6 = uilabel(app.Finger2Panel_2);
            app.Label_6.Text = '45';
            app.Finger3Panel_2 = uipanel(app.SchunkDexterousHandTab);
            app.Finger3Panel_2.Title = 'Finger 3';
            app.Label_7 = uilabel(app.Finger3Panel_2);
            app.Label_7.Text = '30';
            app.Joint1Knob_7Label = uilabel(app.Finger3Panel_2);
            app.Joint1Knob_7Label.Text = 'Joint 1';
            app.Joint1Knob_7 = uiknob(app.Finger3Panel_2, 'continuous');
            app.Joint1Knob_7.Limits = [-90 90];
            app.Joint1Knob_7.ValueChangingFcn = createCallbackFcn(app, @Joint1Knob_7ValueChanging, true);
            app.Joint1Knob_7.Value = 30;
            app.Joint2Knob_7Label = uilabel(app.Finger3Panel_2);
            app.Joint2Knob_7Label.Text = 'Joint 2';
            app.Joint2Knob_7 = uiknob(app.Finger3Panel_2, 'continuous');
            app.Joint2Knob_7.Limits = [-90 90];
            app.Joint2Knob_7.ValueChangingFcn = createCallbackFcn(app, @Joint2Knob_7ValueChanging, true);
            app.Joint2Knob_7.Value = -30;
            app.Label_8 = uilabel(app.Finger3Panel_2);
            app.Label_8.Text = '-30';
            app.ToolsPanel = uipanel(app.SchunkDexterousHandTab);
            app.ToolsPanel.Title = 'Tools';
            app.ApproximateVolumeButton = uibutton(app.ToolsPanel, 'push');
            app.ApproximateVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateVolumeButtonPushed, true);
            app.ApproximateVolumeButton.Text = 'Approximate Volume';
            app.mm3Label = uilabel(app.ToolsPanel);
            app.mm3Label.Text = '0 mm^3';
            app.SweepSwitchLabel = uilabel(app.ToolsPanel);
            app.SweepSwitchLabel.Text = 'Sweep';
            app.SweepSwitch = uiswitch(app.ToolsPanel, 'slider');
            app.MaximizeVolumewithinGraspTypeButton = uibutton(app.ToolsPanel, 'push');
            app.MaximizeVolumewithinGraspTypeButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizeVolumewithinGraspTypeButtonPushed, true);
            app.MaximizeVolumewithinGraspTypeButton.Text = 'Maximize Volume within Grasp Type';
            app.mm3Label_2 = uilabel(app.ToolsPanel);
            app.mm3Label_2.Text = '0 mm^3';
            app.ImportPrimitiveShapeButton = uibutton(app.ToolsPanel, 'push');
            app.ImportPrimitiveShapeButton.Text = 'Import Primitive Shape...';
            app.ResetButton = uibutton(app.ToolsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportasSTLFileButton = uibutton(app.ToolsPanel, 'push');
            app.ExportasSTLFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasSTLFileButtonPushed, true);
            app.ExportasSTLFileButton.Text = 'Export as STL File';
            app.CoordinatesPanel = uipanel(app.SchunkDexterousHandTab);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.Joint2Label = uilabel(app.CoordinatesPanel);
            app.Joint2Label.Text = 'Joint 2:';
            app.Joint3Label = uilabel(app.CoordinatesPanel);
            app.Joint3Label.Text = 'Joint 3:';
            app.FingertipLabel = uilabel(app.CoordinatesPanel);
            app.FingertipLabel.Text = 'Fingertip:';
            app.CoordinatesPanel_2 = uipanel(app.SchunkDexterousHandTab);
            app.CoordinatesPanel_2.Title = 'Coordinates';
            app.Joint2Label_2 = uilabel(app.CoordinatesPanel_2);
            app.Joint2Label_2.Text = 'Joint 2:';
            app.Joint3Label_2 = uilabel(app.CoordinatesPanel_2);
            app.Joint3Label_2.Text = 'Joint 3:';
            app.FingertipLabel_2 = uilabel(app.CoordinatesPanel_2);
            app.FingertipLabel_2.Text = 'Fingertip:';
            app.CoordinatesPanel_3 = uipanel(app.SchunkDexterousHandTab);
            app.CoordinatesPanel_3.Title = 'Coordinates';
            app.Joint1Label = uilabel(app.CoordinatesPanel_3);
            app.Joint1Label.Text = 'Joint 1:';
            app.Joint2Label_3 = uilabel(app.CoordinatesPanel_3);
            app.Joint2Label_3.Text = 'Joint 2:';
            app.FingertipLabel_3 = uilabel(app.CoordinatesPanel_3);
            app.FingertipLabel_3.Text = 'Fingertip:';
            app.AllegroRightHandTab = uitab(app.TabGroup);
            app.AllegroRightHandTab.Title = 'Allegro Right Hand';
            app.Finger4Panel = uipanel(app.AllegroRightHandTab);
            app.Finger4Panel.Title = 'Finger 4';
            app.Joint1Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint1Knob_4Label.Text = 'Joint 1';
            app.Joint1Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint2Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint2Knob_4Label.Text = 'Joint 2';
            app.Joint2Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint3Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint3Knob_4Label.Text = 'Joint 3';
            app.Joint3Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Joint4Knob_4Label = uilabel(app.Finger4Panel);
            app.Joint4Knob_4Label.Text = 'Joint 4';
            app.Joint4Knob_4 = uiknob(app.Finger4Panel, 'continuous');
            app.Finger3Panel = uipanel(app.AllegroRightHandTab);
            app.Finger3Panel.Title = 'Finger 3';
            app.Joint1Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint1Knob_3Label.Text = 'Joint 1';
            app.Joint1Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint2Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint2Knob_3Label.Text = 'Joint 2';
            app.Joint2Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint3Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint3Knob_3Label.Text = 'Joint 3';
            app.Joint3Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Joint4Knob_3Label = uilabel(app.Finger3Panel);
            app.Joint4Knob_3Label.Text = 'Joint 4';
            app.Joint4Knob_3 = uiknob(app.Finger3Panel, 'continuous');
            app.Finger2Panel = uipanel(app.AllegroRightHandTab);
            app.Finger2Panel.Title = 'Finger 2';
            app.Joint1Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint1Knob_2Label.Text = 'Joint 1';
            app.Joint1Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint2Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint2Knob_2Label.Text = 'Joint 2';
            app.Joint2Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint3Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint3Knob_2Label.Text = 'Joint 3';
            app.Joint3Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Joint4Knob_2Label = uilabel(app.Finger2Panel);
            app.Joint4Knob_2Label.Text = 'Joint 4';
            app.Joint4Knob_2 = uiknob(app.Finger2Panel, 'continuous');
            app.Finger1Panel = uipanel(app.AllegroRightHandTab);
            app.Finger1Panel.Title = 'Finger 1';
            app.Joint1KnobLabel = uilabel(app.Finger1Panel);
            app.Joint1KnobLabel.Text = 'Joint 1';
            app.Joint1Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint2KnobLabel = uilabel(app.Finger1Panel);
            app.Joint2KnobLabel.Text = 'Joint 2';
            app.Joint2Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint3KnobLabel = uilabel(app.Finger1Panel);
            app.Joint3KnobLabel.Text = 'Joint 3';
            app.Joint3Knob = uiknob(app.Finger1Panel, 'continuous');
            app.Joint4KnobLabel = uilabel(app.Finger1Panel);
            app.Joint4KnobLabel.Text = 'Joint 4';
            app.Joint4Knob = uiknob(app.Finger1Panel, 'continuous');
            app.AddaManipulatorTab = uitab(app.TabGroup);
            app.AddaManipulatorTab.Title = 'Add a Manipulator...';
            app.ControlPanel = uipanel(app.ManipulatorWorkVolumeSimulatorUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.ZoominButton = uibutton(app.ControlPanel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ResetZoomButton = uibutton(app.ControlPanel, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.Rotate3dButton = uibutton(app.ControlPanel, 'push');
            app.Rotate3dButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate3dButtonPushed, true);
            app.Rotate3dButton.Text = 'Rotate 3d';
            app.PanButton = uibutton(app.ControlPanel, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.AxisLimitsSwitchLabel = uilabel(app.ControlPanel);
            app.AxisLimitsSwitchLabel.Text = 'Axis Limits';
            app.AxisLimitsSwitch = uiswitch(app.ControlPanel, 'slider');
            app.AxisLimitsSwitch.Items = {'Auto ', 'Manual'};
            app.AxisLimitsSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsSwitchValueChanged, true);
            app.AxisLimitsSwitch.Value = 'Auto ';
            % Show the figure after all components are created
            app.ManipulatorWorkVolumeSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app002

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManipulatorWorkVolumeSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManipulatorWorkVolumeSimulatorUIFigure)
        end
    end
end